classdef MagneticFieldGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ZeroButton                    matlab.ui.control.Button
        ApplyButton                   matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        FieldPanel                    matlab.ui.container.Panel
        xButton                       matlab.ui.control.Button
        QuickFieldDirectionLabel      matlab.ui.control.Label
        mxButton                      matlab.ui.control.Button
        yButton                       matlab.ui.control.Button
        myButton                      matlab.ui.control.Button
        xyButton                      matlab.ui.control.Button
        mxyButton                     matlab.ui.control.Button
        Rotate                        matlab.ui.control.Button
        Stop                          matlab.ui.control.Button
        FieldXYStrengthmTLabel        matlab.ui.control.Label
        FieldStrength                 matlab.ui.control.NumericEditField
        FieldXYDirectiondegLabel      matlab.ui.control.Label
        FieldXYDirection              matlab.ui.control.NumericEditField
        FieldStrengthmTGaugeLabel     matlab.ui.control.Label
        FieldStrengthmTGauge          matlab.ui.control.SemicircularGauge
        FreqHzLabel                   matlab.ui.control.Label
        Freq                          matlab.ui.control.NumericEditField
        counterfreqEditFieldLabel     matlab.ui.control.Label
        counterfreqEditField          matlab.ui.control.NumericEditField
        CyclesEditFieldLabel          matlab.ui.control.Label
        Cycles                        matlab.ui.control.NumericEditField
        timeEditFieldLabel            matlab.ui.control.Label
        time                          matlab.ui.control.NumericEditField
        RotationPointsEditFieldLabel  matlab.ui.control.Label
        RotationPoints                matlab.ui.control.NumericEditField
        GradientModeButton            matlab.ui.control.StateButton
        FieldZmTEditFieldLabel        matlab.ui.control.Label
        FieldZ                        matlab.ui.control.NumericEditField
        CW                            matlab.ui.control.StateButton
        RotationAxisButtonGroup       matlab.ui.container.ButtonGroup
        XY                            matlab.ui.control.ToggleButton
        XZ                            matlab.ui.control.ToggleButton
        YZ                            matlab.ui.control.ToggleButton
        ThirdAxisConstFieldValuemTLabel  matlab.ui.control.Label
        ThirdAxisField                matlab.ui.control.NumericEditField
        VibrateX                      matlab.ui.control.Button
        VibfreqX                      matlab.ui.control.NumericEditField
        VibDutyX                      matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        TimeLabel                     matlab.ui.control.Label
        FrqLabel                      matlab.ui.control.Label
        DutyLabel                     matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        RunandTumble                  matlab.ui.control.StateButton
        TumbleTimeEditFieldLabel      matlab.ui.control.Label
        TumbleTime                    matlab.ui.control.NumericEditField
        RotDiffusion                  matlab.ui.control.StateButton
        DiffConstEditFieldLabel       matlab.ui.control.Label
        DiffConst                     matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
    end

    
    properties (Access = public)
            sctr1 = daq('ni');
            sctr2 = daq('ni');
            sctr3 = daq('ni');
            sctr4 = daq('ni');
            counter1;
            counter2;
            counter3;
            counter4;
            counterfreq;
            cyclenum;
            sDig1 = daq('ni');
            sDig2 = daq('ni');
            sDig3 = daq('ni');
            
    end
    
    properties (Access = private)
            maxField = 7;
            %slopex = 1;%set slope which converts a field input into a duty cycle
            %slopey = 1/7; %currently calibrated for 5.5 volts
            %offsetx = 0;%adjust offset of duty cycle such that the field is zero when set to zero
            %offsety = 0;
            rotationRes;
            pax;
            Axes;
            stop = 0;
            %xfactor = 1;
            %yfactor = 1; %to account for differences in field strength between the axes at the same duty cycle. Set to 1 if you want to keep the maximum at 1 and the minimum at 0.
            deltaCycle;
    end
  
    
    methods (Access = private)
        
        function [] = DisplayField(app,BMag,BOri)
            app.FieldStrengthmTGauge.Value = BMag;
            app.Axes = axes(app.FieldPanel);
            c=compass(app.Axes,[cos(BOri*pi/180)],[sin(BOri*pi/180)]);
            c1 = c(1);
            c1.LineWidth = 2;
        end
        
        function dutycycley = dutyy(~,fieldy)
        	%dutycycley = (9.8865-3.63662*sqrt(7.319-1.004*fieldy))/7;
            dutycycley = fieldy/7;
                 %converts the desired y-field into a duty cycle that will give
                 %that field, according to a second order polynomial fit to
                 %measured data at the reference voltage of 5.5 V.
        end
        
        function dutycyclex = dutyx(~,fieldx)
        	%dutycyclex = (1.3142-0.20454*sqrt(39.8379-4.889*(fieldx)));
            dutycyclex = fieldx/7;
                 %converts the desired x-field into a duty cycle that will give
                 %that field, according to a second order polynomial fit to
                 %measured data at the reference voltage of 5.5 V.
        end
        
        function dutycyclez = dutyz(~,fieldz)
        	%dutycyclex = (1.3142-0.20454*sqrt(39.8379-4.889*(fieldx)));
            dutycyclez = fieldz/7;
                 %converts the desired x-field into a duty cycle that will give
                 %that field, according to a second order polynomial fit to
                 %measured data at the reference voltage of 5.5 V.
        end
        
        function [] = SetConstField(app,BMag,BOri)
            
            xcomp = abs(cos(BOri*pi/180));
            ycomp = abs(sin(BOri*pi/180));
            
            %scalex = BMag*app.xfactor*app.slopex;
            %scaley = BMag*app.yfactor*app.slopey;
            xduty = dutyx(app,BMag*xcomp);
            yduty = dutyy(app,BMag*ycomp);
            
            if xduty >= 1
                xduty = 0.999;%don't allow the maxiumum duty cycle to be surpassed
            elseif xduty <=0.001
                xduty = 0.001;
            end
            if yduty >= 1
                yduty = 0.999;
            elseif yduty <=0.001
                yduty = 0.001;
            end

            
            %app.counter1.DutyCycle = xcomp*scalex+app.offsetx+0.001;
            %app.counter2.DutyCycle = ycomp*scaley+app.offsety+0.001;     
            app.counter1.DutyCycle = xduty;  
            app.counter2.DutyCycle = yduty;  
                
                    
            if BOri <= 90 && BOri > -90
                %outputSingleScan(app.sDig1,0);
                write(app.sDig1,0);
            elseif BOri <= -90 || BOri > 90
                %outputSingleScan(app.sDig1,1);
                write(app.sDig1,1);
            end
            
            if BOri <= 0 && BOri > -180
                %outputSingleScan(app.sDig2,1);
                write(app.sDig3,1);
            elseif BOri <= 180 && BOri > 0
                %outputSingleScan(app.sDig2,0);
                write(app.sDig3,0);
            end
            DisplayField(app,BMag,BOri);
        end
    end
           

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %This runs when you open the app 
            app.counter1 = addoutput(app.sctr1,'Dev2', 0, 'PulseGeneration');
            app.counter2 = addoutput(app.sctr2,'Dev2', 1, 'PulseGeneration');
            app.counter3 = addoutput(app.sctr3,'Dev2', 2, 'PulseGeneration');
            app.counter4 = addoutput(app.sctr4,'Dev2', 3, 'PulseGeneration'); %vibration signal

            addoutput(app.sDig1,"Dev2","Port1/Line6","Digital");%x-axis
            addoutput(app.sDig2,"Dev2","Port1/Line7","Digital");%z-axis
            addoutput(app.sDig3,"Dev2","Port1/Line5","Digital");%y-axis
            
            
%             app.sctr1.IsContinuous = true;  
%             app.sctr2.IsContinuous = true;  
            
            %s1.IsContinuous = true; % ensures the digital pulse train is continouse
            %instead of finite
            % counter1.Frequency = 30;
            % counter2.Frequency = 30;
            %app.counterfreq = app.counterfreqEditField.Value;
%             app.counter1.Frequency = app.counterfreq;
%             app.counter2.Frequency = app.counterfreq;
            app.counter1.Frequency = 5000;
            app.counter2.Frequency = 5000;
            app.counter3.Frequency = 5000;
            
            app.counter1.DutyCycle = .001;
            app.counter2.DutyCycle = .001;
            app.counter3.DutyCycle = .001;
            
            
%             startBackground(app.sctr1);
%             startBackground(app.sctr2);
            start(app.sctr1, "continuous");
            start(app.sctr2, "continuous");
            start(app.sctr3, "continuous");
            
            app.Axes = axes(app.FieldPanel);
            c=compass(app.Axes,[0],[0]);
            c1 = c(1);
            c1.LineWidth = 2;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            BOri = app.FieldXYDirection.Value;
            BMag = app.FieldStrength.Value;
            
            app.FieldStrengthmTGauge.Value = BMag;
            
            xcomp = abs(cos(BOri*pi/180));
            ycomp = abs(sin(BOri*pi/180));
            
            xduty = dutyx(app,BMag*xcomp);
            yduty = dutyy(app,BMag*ycomp);
            
            zduty = dutyz(app,abs(app.FieldZ.Value));


            if xduty >= 1
                xduty = 0.999;%don't allow the maxiumum duty cycle to be surpassed
            elseif xduty <=0.001
                xduty = 0.001;
            end
            
            if yduty >= 1
                yduty = 0.999;
            elseif yduty <=0.001
                yduty = 0.001;
            end
            
            if zduty >= 1
                zduty = 0.999;
            elseif zduty <=0.001
                zduty = 0.001;
            end
            
            %app.counter1.DutyCycle = xcomp*scalex+app.offsetx+0.001;
            %app.counter2.DutyCycle = ycomp*scaley+app.offsety+0.001;  
            app.counter1.DutyCycle = xduty; 
            app.counter2.DutyCycle = yduty;          
            app.counter3.DutyCycle = zduty;    
                
                    
            if BOri <= 90 && BOri > -90%x
                %outputSingleScan(app.sDig1,0);
                write(app.sDig1,0);
            elseif BOri <= -90 || BOri > 90
                %outputSingleScan(app.sDig1,1);
                write(app.sDig1,1);
            end
            
            if BOri <= 0 && BOri > -180%y
                %outputSingleScan(app.sDig2,1);
                write(app.sDig3,1);
            elseif BOri <= 180 && BOri > 0
                %outputSingleScan(app.sDig2,0);
                write(app.sDig3,0);
            end
            
            if app.FieldZ.Value > 0%z
                write(app.sDig2,1);
            else
                write(app.sDig2,0);
            end
            
            app.Axes = axes(app.FieldPanel);
            c=compass(app.Axes,[cos(BOri*pi/180)],[sin(BOri*pi/180)]);
            c1 = c(1);
            c1.LineWidth = 2;
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
                app.counter1.DutyCycle = .001;
                app.counter2.DutyCycle = .001;
                app.counter3.DutyCycle = .001;
                
                app.FieldStrengthmTGauge.Value = 0;
                
%                 outputSingleScan(app.sDig1,0);
%                 outputSingleScan(app.sDig2,0);
                write(app.sDig1,0);
                write(app.sDig2,0);
                write(app.sDig3,0);
                
                app.Axes = axes(app.FieldPanel);
                c=compass(app.Axes,[0],[0]);
                c1 = c(1);
                c1.LineWidth = 2;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
                app.counter1.DutyCycle = .001;
                app.counter2.DutyCycle = .001;
                app.counter3.DutyCycle = .001;
                
                app.FieldStrengthmTGauge.Value = 0;
%                 
%                 outputSingleScan(app.sDig1,0);
%                 outputSingleScan(app.sDig2,0);
                write(app.sDig1,0);
                write(app.sDig2,0);
                write(app.sDig3,0);
                
                daqreset;
                closereq()
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            BOri = 0;
            %BMag = app.maxField;
            BMag = app.FieldStrength.Value;
            SetConstField(app,BMag,BOri);
        end

        % Button pushed function: mxButton
        function mxButtonPushed(app, event)
            BOri = 180;
            %BMag = app.maxField;
            BMag = app.FieldStrength.Value;
            SetConstField(app,BMag,BOri);
        end

        % Button pushed function: yButton
        function yButtonPushed(app, event)
            BOri = 90;
            %BMag = app.maxField;
            BMag = app.FieldStrength.Value;
            SetConstField(app,BMag,BOri);
        end

        % Button pushed function: myButton
        function myButtonPushed(app, event)
            BOri = -90;
            %BMag = app.maxField;
            BMag = app.FieldStrength.Value;
            SetConstField(app,BMag,BOri);
        end

        % Button pushed function: xyButton
        function xyButtonPushed(app, event)
            BOri = 45;
            %BMag = app.maxField;
            BMag = app.FieldStrength.Value;
            SetConstField(app,BMag,BOri);
        end

        % Button pushed function: mxyButton
        function mxyButtonPushed(app, event)
            BOri = -45;
            %BMag = app.maxField;
            BMag = app.FieldStrength.Value;
            SetConstField(app,BMag,BOri);
        end

        % Button pushed function: Stop
        function StopPushed(app, event)
                app.stop=1;    
                app.counter1.DutyCycle = .001;
                app.counter2.DutyCycle = .001;
                app.counter3.DutyCycle = .001;
                DisplayField(app,0,0)
        end

        % Button pushed function: Rotate
        function RotatePushed(app, event)
            app.stop = 0;
            freq = app.Freq.Value;
            BMag = app.FieldStrength.Value;
            
            if freq <= 4            
                app.rotationRes = 20;
                app.RotationPoints.Value = app.rotationRes;
                %how many different points will go into the sine wave to approximate a continuous curve. I want this times the frequency, both inverted, to be at least greater than 10 ms for the timer to work properly, because that's how long it takes to run the code at each iteration
            elseif freq <= 8 
                    app.rotationRes = 10;
                    app.RotationPoints.Value = app.rotationRes;
                    %decrease the number of discrete steps to keep up with the higher frequency. At 10 it should work up to 8 Hz. 
            else
                app.rotationRes = 5;
                app.RotationPoints.Value = app.rotationRes;
                % decrease again, altough now it is not really a sine wave but more like a square wave. This should work up to 16 Hz.
            end
            
            app.FieldStrengthmTGauge.Value = BMag;
            
            angles = linspace(0,360,app.rotationRes);
            if app.CW.Value
                angles = 360-angles;
            end
            xcomps = abs(cos(angles*pi/180));
            ycomps = abs(sin(angles*pi/180));
            Dig1vals = ones(app.rotationRes,1);
            Dig1vals(angles<=90) = 0;
            Dig1vals(angles>270) = 0;
            Dig2vals = zeros(app.rotationRes,1);
            Dig2vals(angles>180) = 1;
            
            %scalex = BMag*app.xfactor*app.slopex;
            %scaley = BMag*app.yfactor*app.slopey;
            if app.XY.Value
                xduty = dutyx(app,BMag*xcomps);%XY rotatation plane. This is actually a vector
                yduty = dutyy(app,BMag*ycomps);
                
                dutyt = dutyy(app,abs(app.ThirdAxisField.Value));%set constant field value of third axis
                if dutyt >= 1 
                    dutyt = 0.999;
                elseif dutyt < 0.001
                    dutyt = 0.001;
                end
                app.counter3.DutyCycle = dutyt; 
                if app.ThirdAxisField.Value > 0
                    write(app.sDig2,1);
                else
                    write(app.sDig2,0);
                end
                
            elseif app.XZ.Value
                xduty = dutyx(app,BMag*xcomps);%XZ rotation plane
                yduty = dutyz(app,BMag*ycomps);    %still calling it y but it's really z now
                
                dutyt = dutyy(app,abs(app.ThirdAxisField.Value));
                if dutyt >= 1 
                    dutyt = 0.999;
                elseif dutyt < 0.001
                    dutyt = 0.001;
                end
                app.counter2.DutyCycle = dutyt; 
                if app.ThirdAxisField.Value > 0
                    write(app.sDig3,1);
                else
                    write(app.sDig3,0);
                end
                
            else
                xduty = dutyy(app,BMag*xcomps);%ZY rotation plane
                yduty = dutyz(app,BMag*ycomps);
                
                dutyt = dutyy(app,abs(app.ThirdAxisField.Value));
                if dutyt >= 1 
                    dutyt = 0.999;
                elseif dutyt < 0.001
                    dutyt = 0.001;
                end
                app.counter1.DutyCycle = dutyt; 
                if app.ThirdAxisField.Value > 0
                    write(app.sDig1,1);
                else
                    write(app.sDig1,0);
                end
                
            end
            
            xduty(xduty >= 1) = 0.999; %don't allow the maxiumum duty cycle to be surpassed
            yduty(yduty >= 1) = 0.999;
            
            xduty(xduty <= 0.001) = 0.001; %don't allow the maxiumum duty cycle to be surpassed
            yduty(yduty <= 0.001) = 0.001;
              
            %xcomps = xcomps*scalex + app.offsetx + 0.001;
            %ycomps = ycomps*scaley + app.offsety + 0.001;  
            xcomps = xduty;
            ycomps = yduty;
            
            app.deltaCycle = 1/length(xcomps);
            
            app.cyclenum = 0;
            tic;
            while ~app.stop
                if app.XY.Value
                    for i = 1:length(xcomps)
                            app.counter1.DutyCycle = xcomps(i);
                            app.counter2.DutyCycle = ycomps(i);   
                            write(app.sDig1,Dig1vals(i));
                            write(app.sDig3,Dig2vals(i));
                            app.cyclenum = app.cyclenum + app.deltaCycle;
                            app.Cycles.Value = app.cyclenum;
                            app.time.Value = toc;
                            while toc < app.cyclenum/freq %don't do anything until the time since the last execution is one 20th of the period, or whatever the number of discrete points is
                            end
                    end
                elseif app.XZ.Value
                    for i = 1:length(xcomps)
                            app.counter1.DutyCycle = xcomps(i);
                            app.counter3.DutyCycle = ycomps(i);   
                            write(app.sDig1,Dig1vals(i));
                            write(app.sDig2,Dig2vals(i));
                            app.cyclenum = app.cyclenum + app.deltaCycle;
                            app.Cycles.Value = app.cyclenum;
                            app.time.Value = toc;
                            while toc < app.cyclenum/freq %don't do anything until the time since the last execution is one 20th of the period, or whatever the number of discrete points is
                            end
                    end    
                else
                    for i = 1:length(xcomps)
                            app.counter2.DutyCycle = xcomps(i);
                            app.counter3.DutyCycle = ycomps(i);   
                            write(app.sDig3,Dig1vals(i));
                            write(app.sDig2,Dig2vals(i));
                            app.cyclenum = app.cyclenum + app.deltaCycle;
                            app.Cycles.Value = app.cyclenum;
                            app.time.Value = toc;
                            while toc < app.cyclenum/freq %don't do anything until the time since the last execution is one 20th of the period, or whatever the number of discrete points is
                            end
                    end
                end
            end
            app.counter1.DutyCycle = .001;
            app.counter2.DutyCycle = .001;
            app.counter3.DutyCycle = .001;
            
            app.FieldStrengthmTGauge.Value = 0;
            
            write(app.sDig1,0);
            write(app.sDig2,0);
            write(app.sDig3,0);
            
            app.Axes = axes(app.FieldPanel);
            c=compass(app.Axes,[0],[0]);
            c1 = c(1);
            c1.LineWidth = 2;
            
            
        end

        % Value changed function: counterfreqEditField
        function counterfreqEditFieldValueChanged(app, event)
            app.counterfreq = app.counterfreqEditField.Value;
            app.counter1.Frequency = app.counterfreq;
            app.counter2.Frequency = app.counterfreq;
        end

        % Value changed function: GradientModeButton
        function GradientModeButtonValueChanged(app, event)
%             gradmode = app.GradientModeButton.Value;
%             outputSingleScan(app.sDig3,gradmode);
        end

        % Value changed function: CW
        function CWValueChanged(app, event)
            if ~app.CW.Value
                app.CW.Text = 'CCW';
                app.CW.BackgroundColor = 'r';
            else
                app.CW.Text = 'CW';%default is this, CW rotation
                app.CW.BackgroundColor = [0.99,0.90,0.12];
            end
            
        end

        % Button pushed function: VibrateX
        function VibrateXButtonPushed(app, event)
            
            
            write(app.sDig1,0);
            write(app.sDig2,0);
            
            BMag = app.FieldStrength.Value;
            
            app.FieldStrengthmTGauge.Value = BMag;
            
          
            xduty = dutyx(app,BMag);
            
            if xduty >= 1
                xduty = 0.999;%don't allow the maxiumum duty cycle to be surpassed
            end
 
            app.counter1.DutyCycle = xduty;
            
            
            app.VibrateX.Frequency = app.VibfreqX.Value; %(Hz) frequency at which opposite facing magnets switch polarity 
            app.VibrateX.DutyCycle = app.VibDutyX.Value; %this should be 0.5 because the direction of strength should be the same whether in the right or left direction
            %app.VibrateX.InitialDelay = 0; %delays start of signal (seconds)
            %start(app.sctr1, "continuous");
            start(app.counter4,"continuous");


            app.T = 0;
            tic
            while app.T == 0
                app.VibrationTime.Value = toc;
                pause(.2)
            end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.counter4);
          
            app.counter2.DutyCycle =0.001;
            app.counter1.DutyCycle =0.001;
            app.FieldStrengthmTGauge.Value = 0;
            app.T = 1;
        end

        % Value changed function: RotDiffusion
        function RotDiffusionValueChanged(app, event)
            value = app.RotDiffusion.Value;
            app.stop = 0;
            if value
                app.DiffConst.BackgroundColor = 'r';
                
                BMag = app.FieldStrength.Value;
        
                BOriDiff=0;
                SetConstField(app,BMag,BOriDiff);
                
                delaytime=1;
                std = sqrt(app.DiffConst.Value*delaytime);
                while ~app.stop
                    tic
                    BOriDiff = normrnd(BOriDiff,std);%choose field direction based on a normal distribution centered on the previous value with a variance equal to the time passed times the diffusion constant specified
                    if toc > delaytime %every tenth of a second update field direction
                        SetConstField(app,BMag,BOriDiff);
                        DisplayField(app,BMag,BOriDiff)
                    end
                end
            else
                app.DiffConst.BackgroundColor = [1 0 1];%magenta
                app.counter1.DutyCycle = .001;
                app.counter2.DutyCycle = .001;
                app.counter3.DutyCycle = .001;
                DisplayField(app,0,0)
            end
        end

        % Value changed function: RunandTumble
        function RunandTumbleValueChanged(app, event)
            value = app.RunandTumble.Value;
            app.stop = 1;
            
            if value
                app.stop = 0;
                app.RunandTumble.BackgroundColor = 'r';
                
                BMag = app.FieldStrength.Value;
                
                BOriDiff=0;
                SetConstField(app,BMag,BOriDiff);
                a = -180;
                b = 180;

                while ~app.stop
                    tic
                    BOriTumb = (b-a).*rand(1) + a;%choose field direction based on a random value between -180 and 180
                    delay = exprnd(app.TumbleTime.Value);%choose a delay time that is exponentially distributed with mean specified
                    if toc > delay %every tenth of a second update field direction
                        SetConstField(app,BMag,BOriTumb);
                        DisplayField(app,BMag,BOriTumb)
                    end
                end
            else
                app.RunandTumble.BackgroundColor = [0.3 0.3 1];%maybe light blue
                app.counter1.DutyCycle = .001;
                app.counter2.DutyCycle = .001;
                app.counter3.DutyCycle = .001;
                DisplayField(app,0,0)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ZeroButton = uibutton(app.GridLayout, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.FieldPanel = uipanel(app.GridLayout);
            app.FieldPanel.Title = 'Field Direction';
            app.xButton = uibutton(app.GridLayout, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.QuickFieldDirectionLabel = uilabel(app.GridLayout);
            app.QuickFieldDirectionLabel.Text = 'Quick Field Direction';
            app.mxButton = uibutton(app.GridLayout, 'push');
            app.mxButton.ButtonPushedFcn = createCallbackFcn(app, @mxButtonPushed, true);
            app.mxButton.Text = '-x';
            app.yButton = uibutton(app.GridLayout, 'push');
            app.yButton.ButtonPushedFcn = createCallbackFcn(app, @yButtonPushed, true);
            app.yButton.Text = 'y';
            app.myButton = uibutton(app.GridLayout, 'push');
            app.myButton.ButtonPushedFcn = createCallbackFcn(app, @myButtonPushed, true);
            app.myButton.Text = '-y';
            app.xyButton = uibutton(app.GridLayout, 'push');
            app.xyButton.ButtonPushedFcn = createCallbackFcn(app, @xyButtonPushed, true);
            app.xyButton.Text = '45';
            app.mxyButton = uibutton(app.GridLayout, 'push');
            app.mxyButton.ButtonPushedFcn = createCallbackFcn(app, @mxyButtonPushed, true);
            app.mxyButton.Text = '-45';
            app.Rotate = uibutton(app.GridLayout, 'push');
            app.Rotate.ButtonPushedFcn = createCallbackFcn(app, @RotatePushed, true);
            app.Rotate.Text = 'Rotate';
            app.Stop = uibutton(app.GridLayout, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopPushed, true);
            app.Stop.Text = 'Stop';
            app.FieldXYStrengthmTLabel = uilabel(app.GridLayout);
            app.FieldXYStrengthmTLabel.Text = 'Field XY Strength (mT)';
            app.FieldStrength = uieditfield(app.GridLayout, 'numeric');
            app.FieldStrength.Limits = [0 7];
            app.FieldStrength.Value = 7;
            app.FieldXYDirectiondegLabel = uilabel(app.GridLayout);
            app.FieldXYDirectiondegLabel.Text = 'Field XY Direction (deg)';
            app.FieldXYDirection = uieditfield(app.GridLayout, 'numeric');
            app.FieldXYDirection.Limits = [-180 180];
            app.FieldStrengthmTGaugeLabel = uilabel(app.GridLayout);
            app.FieldStrengthmTGaugeLabel.Text = 'Field Strength (mT)';
            app.FieldStrengthmTGauge = uigauge(app.GridLayout, 'semicircular');
            app.FieldStrengthmTGauge.Limits = [0 7];
            app.FreqHzLabel = uilabel(app.GridLayout);
            app.FreqHzLabel.Text = 'Freq (Hz)';
            app.Freq = uieditfield(app.GridLayout, 'numeric');
            app.Freq.Limits = [0.0001 16];
            app.Freq.Value = 1;
            app.counterfreqEditFieldLabel = uilabel(app.GridLayout);
            app.counterfreqEditFieldLabel.Text = 'counter freq';
            app.counterfreqEditField = uieditfield(app.GridLayout, 'numeric');
            app.counterfreqEditField.Limits = [0 100000];
            app.counterfreqEditField.RoundFractionalValues = 'on';
            app.counterfreqEditField.ValueChangedFcn = createCallbackFcn(app, @counterfreqEditFieldValueChanged, true);
            app.counterfreqEditField.Value = 1000;
            app.CyclesEditFieldLabel = uilabel(app.GridLayout);
            app.CyclesEditFieldLabel.Text = 'Cycles';
            app.Cycles = uieditfield(app.GridLayout, 'numeric');
            app.timeEditFieldLabel = uilabel(app.GridLayout);
            app.timeEditFieldLabel.Text = 'time';
            app.time = uieditfield(app.GridLayout, 'numeric');
            app.RotationPointsEditFieldLabel = uilabel(app.GridLayout);
            app.RotationPointsEditFieldLabel.Text = 'Rotation Points';
            app.RotationPoints = uieditfield(app.GridLayout, 'numeric');
            app.GradientModeButton = uibutton(app.GridLayout, 'state');
            app.GradientModeButton.ValueChangedFcn = createCallbackFcn(app, @GradientModeButtonValueChanged, true);
            app.GradientModeButton.Visible = 'off';
            app.GradientModeButton.Text = 'Gradient Mode';
            app.FieldZmTEditFieldLabel = uilabel(app.GridLayout);
            app.FieldZmTEditFieldLabel.Text = 'Field Z (mT)';
            app.FieldZ = uieditfield(app.GridLayout, 'numeric');
            app.FieldZ.Limits = [-7 7];
            app.CW = uibutton(app.GridLayout, 'state');
            app.CW.ValueChangedFcn = createCallbackFcn(app, @CWValueChanged, true);
            app.CW.Text = 'CW';
            app.CW.Value = true;
            app.RotationAxisButtonGroup = uibuttongroup(app.GridLayout);
            app.RotationAxisButtonGroup.Title = 'Rotation Axis';
            app.XY = uitogglebutton(app.RotationAxisButtonGroup);
            app.XY.Text = 'XY';
            app.XY.Value = true;
            app.XZ = uitogglebutton(app.RotationAxisButtonGroup);
            app.XZ.Text = 'XZ';
            app.YZ = uitogglebutton(app.RotationAxisButtonGroup);
            app.YZ.Text = 'YZ';
            app.ThirdAxisConstFieldValuemTLabel = uilabel(app.GridLayout);
            app.ThirdAxisConstFieldValuemTLabel.Text = {'Third Axis '; 'Const Field '; 'Value (mT)'};
            app.ThirdAxisField = uieditfield(app.GridLayout, 'numeric');
            app.ThirdAxisField.Limits = [-7 7];
            app.VibrateX = uibutton(app.GridLayout, 'push');
            app.VibrateX.ButtonPushedFcn = createCallbackFcn(app, @VibrateXButtonPushed, true);
            app.VibrateX.Text = 'Vibrate X';
            app.VibfreqX = uieditfield(app.GridLayout, 'numeric');
            app.VibfreqX.Limits = [1 50];
            app.VibfreqX.Value = 1;
            app.VibDutyX = uieditfield(app.GridLayout, 'numeric');
            app.VibDutyX.Limits = [0.001 0.99];
            app.VibDutyX.Value = 0.5;
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.TimeLabel = uilabel(app.GridLayout);
            app.TimeLabel.Text = 'Time';
            app.FrqLabel = uilabel(app.GridLayout);
            app.FrqLabel.Text = 'Frq';
            app.DutyLabel = uilabel(app.GridLayout);
            app.DutyLabel.Text = 'Duty';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunandTumble = uibutton(app.GridLayout, 'state');
            app.RunandTumble.ValueChangedFcn = createCallbackFcn(app, @RunandTumbleValueChanged, true);
            app.RunandTumble.Text = 'Run and Tumble';
            app.TumbleTimeEditFieldLabel = uilabel(app.GridLayout);
            app.TumbleTimeEditFieldLabel.Text = 'Tumble Time';
            app.TumbleTime = uieditfield(app.GridLayout, 'numeric');
            app.TumbleTime.Limits = [0.1 Inf];
            app.TumbleTime.Value = 5;
            app.RotDiffusion = uibutton(app.GridLayout, 'state');
            app.RotDiffusion.ValueChangedFcn = createCallbackFcn(app, @RotDiffusionValueChanged, true);
            app.RotDiffusion.Text = 'Rot Diffusion';
            app.DiffConstEditFieldLabel = uilabel(app.GridLayout);
            app.DiffConstEditFieldLabel.Text = 'Diff Const';
            app.DiffConst = uieditfield(app.GridLayout, 'numeric');
            app.DiffConst.Limits = [0.1 Inf];
            app.DiffConst.Value = 5;
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'Diff Const is in squared degrees per second'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagneticFieldGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end